classdef AppScope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        StartButton            matlab.ui.control.Button
        LogTextArea            matlab.ui.control.TextArea
        TriggerLampLabel       matlab.ui.control.Label
        TriggerLamp            matlab.ui.control.Lamp
        LeftEditFieldLabel     matlab.ui.control.Label
        LeftEditField          matlab.ui.control.NumericEditField
        RightEditFieldLabel    matlab.ui.control.Label
        RightEditField         matlab.ui.control.NumericEditField
        AverageEditFieldLabel  matlab.ui.control.Label
        AverageEditField       matlab.ui.control.NumericEditField
        ChannelDropDownLabel   matlab.ui.control.Label
        ChannelDropDown        matlab.ui.control.DropDown
        SelectDataFileButton   matlab.ui.control.Button
        SelelctTagFileButton   matlab.ui.control.Button
        TagEditFieldLabel      matlab.ui.control.Label
        TagEditField           matlab.ui.control.EditField
        SaveDataButton         matlab.ui.control.Button
        AutoSaveLabel          matlab.ui.control.Label
        AutoSaveSwitch         matlab.ui.control.Switch
        LeftButton             matlab.ui.control.Button
        RightButton            matlab.ui.control.Button
    end

    properties (Access = public)
        % The timer instance for trigger state checking.
        % The code is written according to https://www.mathworks.com/matlabcentral/answers/354713-can-the-matlab-app-designer-support-the-timer
        % The definition may not be moved to elsewhere.
        trig_timer = timer('Period', 0.01, 'TasksToExecute', Inf, ...
            'ExecutionMode', 'fixedSpacing','BusyMode','drop');
        
        % The timer instance for moving edges while pressing the key
        key_timer = timer('StartDelay',0.5,'Period', 0.1, 'TasksToExecute', Inf, ...
            'ExecutionMode', 'fixedSpacing','BusyMode','drop');
        
        key_direction % The direction the selected edge moves in while pressing the key
        edge_key = true; % Decide which edge of the average area will be modified by pressing left or right arrow keys
        % 0 -> left, 1 -> right
        existdevice = false; % Used when test codes without a real device
        waveform % The waveform data.
        xstep = 1; % The x interval between two adjacent data points
        edge1 = 0; % x coordinate of the first edge of the average area
        edge2 = 0; % x coordinate of the second edge of the average area
    end
    properties (Access = private)
        scope % The scope object
        fid_data = -1; % File ID of the file to write data in
        fid_tag = -1;% File ID of the file containing the tag of each set of data
        edge_click = false; % Decide which edge of the average area will be modified by mouse click
        % 0 -> left, 1 -> right
        pressed_key = '' % The record of which key (<- or ->) has been pressed
    end
    
    methods (Access = public)
        function DrawFigure(app)
            plot(app.UIAxes,app.waveform(1,:),app.waveform(2,:))
            if app.waveform(1,1) > app.edge1 || app.edge1 > app.waveform(1,length(app.waveform(1,:))) || ...
                    app.waveform(1,1) > app.edge2 || app.edge2 > app.waveform(1,length(app.waveform(1,:)))
                app.edge1 = app.waveform(1,1);
                app.edge2 = app.waveform(1,length(app.waveform(1,:)));
            end
            app.LeftEditField.Value = min(app.edge1,app.edge2);
            app.RightEditField.Value = max(app.edge1,app.edge2);
            if app.edge_click
                lw2 = 0.75;
                lw1 = 1.5;
            else
                lw1 = 0.75;
                lw2 = 1.5;
            end
            hold(app.UIAxes,'on')
            if app.edge1 > app.edge2
                plot(app.UIAxes,[app.edge1,app.edge1],app.UIAxes.YLim,"LineWidth",lw1)
                plot(app.UIAxes,[app.edge2,app.edge2],app.UIAxes.YLim,"LineWidth",lw2)
            else
                plot(app.UIAxes,[app.edge2,app.edge2],app.UIAxes.YLim,"LineWidth",lw2)
                plot(app.UIAxes,[app.edge1,app.edge1],app.UIAxes.YLim,"LineWidth",lw1)
            end
            index1=find(app.waveform(1,:)>= min(app.edge1,app.edge2),1);
            index2=find(app.waveform(1,:)>= max(app.edge1,app.edge2),1);
            plot(app.UIAxes,app.waveform(1,index1:index2),app.waveform(2,index1:index2),'r','LineWidth',2)
            app.AverageEditField.Value = mean(app.waveform(2,index1:index2));
            hold(app.UIAxes,'off')
        end
    end
    
    methods (Access = private)
        
        % Initialize the scope object, specialized for Tektronix TDS1001B.
        function initScope(app)
            try
                % Find a VISA-USB object.
                app.scope = instrfind('Type', 'visa-usb', 'RsrcName', 'USB0::0x0699::0x0362::C103870::0::INSTR', 'Tag', '');
                
                % Create the VISA-USB object if it does not exist
                % otherwise use the object that was found.
                if isempty(app.scope)
                    app.scope = visa('NI', 'USB0::0x0699::0x0362::C103870::0::INSTR');
                else
                    fclose(app.scope);
                    app.scope = app.scope(1);
                end
                
                % Set the software (Matlab) input buffer so that the waveform data can be read in one time.
                set(app.scope, 'InputBufferSize', 20000);
                
                % Connect to instrument object.
                fopen(app.scope);
                
                % Update the log.
                app.LogTextArea.Value = {'Device Connected';' '};
            catch exception
                option=uiconfirm(app.UIFigure,exception.message,'Cannot Connect to the Device',...
                    'Icon','error','Options',{'Retry','Close'});
                if strcmp(option,'Retry')
                    app.initScope;
                else
                    app.UIFigureCloseRequest;
                end
            end
        end
        
        function initApp(app)
            fid_data_path=fopen('DataFilePath.txt');
            if fid_data_path < 3
                app.LogTextArea.Value = [app.LogTextArea.Value;'The path of the data file is not set. Cannot save data.';' '];
            else
                data_path = fgetl(fid_data_path);
                app.LogTextArea.Value = [app.LogTextArea.Value; 'The path of the data file is set as :';data_path;' '];
                app.fid_data = fopen(data_path);
                if app.fid_data < 3
                    app.LogTextArea.Value = [app.LogTextArea.Value; 'Cannot open the data file.';' '];
                else
                    [~,name,ext]=fileparts(data_path);
                    app.LogTextArea.Value = [app.LogTextArea.Value; name,ext,' opened.';' '];
                end
            end
            
            
            fid_tag_path=fopen('TagFilePath.txt');
            if fid_tag_path < 3
                app.LogTextArea.Value = [app.LogTextArea.Value; 'The path of the tag file is not set. Tag is set to -1.';' '];
                app.TagEditField.Value = '-1';
            else
                tag_path = fgetl(fid_tag_path);
                app.LogTextArea.Value = [app.LogTextArea.Value; 'The path of the tag file is set as :';tag_path;' '];
                app.fid_tag = fopen(tag_path);
                if app.fid_tag < 3
                    app.LogTextArea.Value = [app.LogTextArea.Value; 'Cannot open the tag file.';' '];
                    app.TagEditField.Value = '-1';
                else
                    app.TagEditField.Value = fgetl(app.fid_tag);
                    [~,name,ext]=fileparts(tag_path);
                    app.LogTextArea.Value = [app.LogTextArea.Value; name,ext,' opened.';' '];
                end
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initApp;
            if app.existdevice
                app.initScope;
                app.trig_timer.TimerFcn = @(~, ~) CheckTrigger(app,app.scope);
                app.trig_timer.Name = 'trig_timer';
            else
                app.waveform=[0:2499;sin((0:2499)/1250*pi)];
                app.DrawFigure;
            end
            app.key_timer.TimerFcn = @(~, ~) MoveEdge(app,5);
            app.trig_timer.Name = 'key_timer';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.existdevice
                stop(app.trig_timer);
                app.StartButton.Text = 'Start';
            end
            result=uiconfirm(app.UIFigure,'Close app?','Confirm Close','Options',{'Confirm','Cancel'});
            if strcmp(result,'Confirm')
                if app.existdevice
                    delete(app.trig_timer)
                    fclose(app.scope);
                end
                delete(app.key_timer)
                delete(app)
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if strcmp(app.StartButton.Text,'Start')
                start(app.trig_timer);
                app.StartButton.Text = 'Stop';
            else
                stop(app.trig_timer);
                app.StartButton.Text = 'Start';
                app.TriggerLamp.Color = [0.8, 0.8, 0.8];
            end
        end

        % Button pushed function: SelectDataFileButton
        function SelectDataFileButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            if isequal(file,0)
                app.LogTextArea.Value=[app.LogTextArea.Value;'User selected Cancel';' '];
            else
                app.LogTextArea.Value=[app.LogTextArea.Value;'The path of the data file is set as :'; fullfile(path,file);' '];
                app.fid_data = fopen(fullfile(path,file));
                if app.fid_data < 3
                    app.LogTextArea.Value = [app.LogTextArea.Value; 'Cannot open the data file.';' '];
                else
                    app.LogTextArea.Value = [app.LogTextArea.Value; file,' opened.';' '];
                end
            end
        end

        % Button pushed function: SelelctTagFileButton
        function SelelctTagFileButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            if isequal(file,0)
                app.LogTextArea.Value=[app.LogTextArea.Value;'User selected Cancel';' '];
            else
                app.LogTextArea.Value=[app.LogTextArea.Value;'The path of the tag file is set as :'; fullfile(path,file);' '];
                app.fid_tag = fopen(fullfile(path,file));
                if app.fid_tag < 3
                    app.LogTextArea.Value = [app.LogTextArea.Value; 'Cannot open the tag file.';' '];
                else
                    app.LogTextArea.Value = [app.LogTextArea.Value; file,' opened.';' '];
                end
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            cp=event.Source.CurrentPoint;
            ip=app.UIAxes.InnerPosition;
            if cp(1) >= ip(1) && cp(1)<=ip(1)+ip(3) && cp(2) >= ip(2) && cp(2)<=ip(2)+ip(4)
                xl=app.UIAxes.XLim;
                x = (cp(1)-ip(1))/ip(3)*(xl(2)-xl(1))+xl(1);
                if app.waveform(1,1) <= x && x <= app.waveform(1,length(app.waveform(1,:)))
                    if app.edge_click
                        app.edge2 = x;
                    else
                        app.edge1 = x;
                    end
                    app.edge_key = app.edge_click;
                    app.edge_click = ~app.edge_click;
                    app.DrawFigure;
                end
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if isempty(app.pressed_key)
                if strcmp(key,'leftarrow')
                    app.pressed_key = key;
                    app.key_direction = -1;
                    MoveEdge(app,1);
                end
                if strcmp(key,'rightarrow')
                    app.pressed_key = key;
                    app.key_direction = 1;
                    MoveEdge(app,1);
                end
                start(app.key_timer);
            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            if strcmp(key,app.pressed_key)
                stop(app.key_timer);
                app.pressed_key = '';
            end
        end

        % Value changed function: LeftEditField
        function LeftEditFieldValueChanged(app, event)
            value = app.LeftEditField.Value;
            if app.waveform(1,1) > value || value > app.waveform(1,length(app.waveform(1,:)))
                uialert(app.UIFigure,'The entered value exceeds the data range.','Value Error')
                app.LeftEditField.Value = event.PreviousValue;
                return
            end
            if app.edge1 > app.edge2
                app.edge2 = value;
                app.edge_click = false;
                app.edge_key = true;
            else
                app.edge1 = value;
                app.edge_click = true;
                app.edge_key = false;
            end
            app.DrawFigure;
        end

        % Value changed function: RightEditField
        function RightEditFieldValueChanged(app, event)
            value = app.RightEditField.Value;
            if app.waveform(1,1) > value || value > app.waveform(1,length(app.waveform(1,:)))
                uialert(app.UIFigure,'The entered value exceeds the data range.','Value Error')
                app.RightEditField.Value = event.PreviousValue;
                return
            end
            if app.edge1 <= app.edge2
                app.edge2 = value;
                app.edge_click = false;
                app.edge_key = true;
            else
                app.edge1 = value;
                app.edge_click = true;
                app.edge_key = false;
            end
            app.DrawFigure;
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            if app.edge1 > app.edge2
                app.edge_click = false;
                app.edge_key = true;
            else
                app.edge_click = true;
                app.edge_key = false;
            end
            app.DrawFigure;
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            if app.edge1 <= app.edge2
                app.edge_click = false;
                app.edge_key = true;
            else
                app.edge_click = true;
                app.edge_key = false;
            end
            app.DrawFigure;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'T (s)')
            ylabel(app.UIAxes, 'U(V)')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.TriggerLampLabel = uilabel(app.UIFigure);
            app.TriggerLampLabel.Text = 'Trigger';
            app.TriggerLamp = uilamp(app.UIFigure);
            app.LeftEditFieldLabel = uilabel(app.UIFigure);
            app.LeftEditFieldLabel.Text = 'Left';
            app.LeftEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftEditField.ValueChangedFcn = createCallbackFcn(app, @LeftEditFieldValueChanged, true);
            app.RightEditFieldLabel = uilabel(app.UIFigure);
            app.RightEditFieldLabel.Text = 'Right';
            app.RightEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightEditField.ValueChangedFcn = createCallbackFcn(app, @RightEditFieldValueChanged, true);
            app.AverageEditFieldLabel = uilabel(app.UIFigure);
            app.AverageEditFieldLabel.Text = 'Average';
            app.AverageEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.Items = {'CH1', 'CH2'};
            app.ChannelDropDown.Value = 'CH1';
            app.SelectDataFileButton = uibutton(app.UIFigure, 'push');
            app.SelectDataFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFileButtonPushed, true);
            app.SelectDataFileButton.Text = 'Select Data File';
            app.SelelctTagFileButton = uibutton(app.UIFigure, 'push');
            app.SelelctTagFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelelctTagFileButtonPushed, true);
            app.SelelctTagFileButton.Text = 'Selelct Tag File';
            app.TagEditFieldLabel = uilabel(app.UIFigure);
            app.TagEditFieldLabel.Text = 'Tag';
            app.TagEditField = uieditfield(app.UIFigure, 'text');
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.Text = 'Save Data';
            app.AutoSaveLabel = uilabel(app.UIFigure);
            app.AutoSaveLabel.Text = 'Auto Save';
            app.AutoSaveSwitch = uiswitch(app.UIFigure, 'slider');
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppScope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end